classdef viewAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PstateTable         matlab.ui.control.Table
        SelectFileButton    matlab.ui.control.Button
        FileEditFieldLabel  matlab.ui.control.Label
        FileField           matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            global Pstate
            
            [fName,fPath,fType]=uigetfile({'*.analyzer';'*.m'},'Select analyzer or config file');
           
            if fType==1 %analyzer file
                app.FileField.Value=fName;
                load(fullfile(fPath,fName),'-mat');
                Pstate=Analyzer.P;       
            elseif fType==2 %config file
                app.FileField.Value=fName;
                run(fullfile(fPath,fName));
            end
            
            
            for i=1:length(Pstate.param)
                parname{i}=Pstate.param{i}{1};
                partype{i}=Pstate.param{i}{2};
                parini{i}=num2str(Pstate.param{i}{3});
                pardb(i)=Pstate.param{i}{4};
                parunit{i}=Pstate.param{i}{5};
            end
            
            
            t=table(parname',partype',parini',pardb',parunit');
            app.PstateTable.Data=t;
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PstateTable = uitable(app.UIFigure);
            app.PstateTable.ColumnName = {'Name'; 'Type'; 'Initial'; 'DB'; 'Unit'};
            app.PstateTable.RowName = {};
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileField = uieditfield(app.UIFigure, 'text');
            app.FileField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end